classdef controlAppAxes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        controlaxesUIFigure  matlab.ui.Figure
        SelectAxesLabel      matlab.ui.control.Label
        AxisList             matlab.ui.control.DropDown
        XLimLabel            matlab.ui.control.Label
        XLimMin              matlab.ui.control.NumericEditField
        XLimMax              matlab.ui.control.NumericEditField
        YLimLabel            matlab.ui.control.Label
        YLimMin              matlab.ui.control.NumericEditField
        YLimMax              matlab.ui.control.NumericEditField
        okButton             matlab.ui.control.Button
        cancelButton         matlab.ui.control.Button
        applyButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        InterruptDialog
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.controlaxesUIFigure.Position(1) = mainapp.UIFigure.Position(1) + round(mainapp.UIFigure.Position(3)/2- app.controlaxesUIFigure.Position(3)/2);
            app.controlaxesUIFigure.Position(2) = mainapp.UIFigure.Position(2) + round(mainapp.UIFigure.Position(4)/2- app.controlaxesUIFigure.Position(4)/2);
            helper.colorThemeApp(app,mainapp.themeSwitch.Value);
            app.InterruptDialog = uiprogressdlg(mainapp.UIFigure);
            app.CallingApp = mainapp;            
            app.XLimMin.Value = mainapp.rfpop1.XLim(1);
            app.XLimMax.Value = mainapp.rfpop1.XLim(2);
            app.YLimMin.Value = mainapp.rfpop1.YLim(1);
            app.YLimMax.Value = mainapp.rfpop1.YLim(2);
            app.okButton.UserData.xlimmin = app.XLimMin.Value;
            app.okButton.UserData.xlimmax = app.XLimMax.Value;
            app.okButton.UserData.ylimmin = app.YLimMin.Value;
            app.okButton.UserData.ylimmax = app.YLimMax.Value;
            app.okButton.UserData.ax = [app.CallingApp.rfpop1, app.CallingApp.rfpop2, app.CallingApp.rfpop3, app.CallingApp.rfpop4];            
        end

        % Value changed function: AxisList
        function AxisListValueChanged(app, event)
            selectedax = app.AxisList.Value;
            %currax = [];
            %mainapp = app.CallingApp;
            switch lower(selectedax)
                case 'auto correlogram'
                    currax = app.CallingApp.acg;
                case 'temporal component'
                    currax = app.CallingApp.tempcomp;                    
                case 'all receptive fields'
                    currax = app.CallingApp.RFall;
                case 'spatial component'
                    currax = app.CallingApp.spatialcomp;
                case 'pca 1'
                    currax = app.CallingApp.pca1;
                case 'pca 2'
                    currax = app.CallingApp.pca2;
                case 'pca 3'
                    currax = app.CallingApp.pca3;
                case 'pca 4'
                    currax = app.CallingApp.pca4;
                case 'all pcas'
                    currax = [app.CallingApp.pca1,app.CallingApp.pca2,app.CallingApp.pca3];
                case 'population auto correlograms'
                    currax = [app.CallingApp.acgpop1,app.CallingApp.acgpop2,app.CallingApp.acgpop3,app.CallingApp.acgpop4];
                case 'off parasol auto correlograms'
                    currax = app.CallingApp.acgpop1;
                case 'on parasol auto correlograms'
                    currax = app.CallingApp.acgpop2;
                case 'off midget auto correlograms'
                    currax = app.CallingApp.acgpop3;
                case 'on midget auto correlograms'
                    currax = app.CallingApp.acgpop4;
                case 'population temporal components'
                    currax = [app.CallingApp.tcpop1, app.CallingApp.tcpop2, app.CallingApp.tcpop3, app.CallingApp.tcpop4];
                case 'off parasol temporal components'
                    currax = app.CallingApp.tcpop1;
                case 'on parasol temporal components'
                    currax = app.CallingApp.tcpop2;
                case 'off midget temporal components'
                    currax = app.CallingApp.tcpop3;
                case 'on midget temporal components'
                    currax = app.CallingApp.tcpop4;
                case 'population receptive fields'
                    currax = [app.CallingApp.rfpop1, app.CallingApp.rfpop2, app.CallingApp.rfpop3, app.CallingApp.rfpop4];
                case 'off parasol receptive fields'
                    currax = app.CallingApp.rfpop1;
                case 'on parasol receptive fields'
                    currax = app.CallingApp.rfpop2;
                case 'off midget receptive fields'
                    currax = app.CallingApp.rfpop3;
                case 'on midget receptive fields'
                    currax = app.CallingApp.rfpop4;
                case 'population nonlinearities'
                    currax = [app.CallingApp.nlpop1, app.CallingApp.nlpop2, app.CallingApp.nlpop3, app.CallingApp.nlpop4];
                case 'off parasol nonlinearities'
                    currax = app.CallingApp.nlpop1;
                case 'on parasol nonlinearities'
                    currax = app.CallingApp.nlpop2;
                case 'off midget nonlinearities'
                    currax = app.CallingApp.nlpop3;
                case 'on midget nonlinearities'
                    currax = app.CallingApp.nlpop4;
                otherwise
                    currax = [app.CallingApp.rfpop1, app.CallingApp.rfpop2, app.CallingApp.rfpop3, app.CallingApp.rfpop4];
            end
            app.XLimMin.Value = currax(1).XLim(1);
            app.XLimMax.Value = currax(1).XLim(2);
            app.YLimMin.Value = currax(1).YLim(1);
            app.YLimMax.Value = currax(1).YLim(2);
            app.okButton.UserData.xlimmin = app.XLimMin.Value;
            app.okButton.UserData.xlimmax = app.XLimMax.Value;
            app.okButton.UserData.ylimmin = app.YLimMin.Value;
            app.okButton.UserData.ylimmax = app.YLimMax.Value;
            app.okButton.UserData.ax = currax;
        end

        % Close request function: controlaxesUIFigure
        function controlAppAxesCloseRequest(app, event)
            close(app.InterruptDialog);
            delete(app)
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            xlimvals = [app.okButton.UserData.xlimmin, app.okButton.UserData.xlimmax];
            ylimvals = [app.okButton.UserData.ylimmin, app.okButton.UserData.ylimmax];
            % updateMainAppAxis(app, app.okButton.UserData.ax , xlimvals, ylimvals)
            for ii = 1:numel(app.okButton.UserData.ax)
                app.okButton.UserData.ax(ii).XLim = xlimvals;
                app.okButton.UserData.ax(ii).YLim = ylimvals;
            end
            delete(app)
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: XLimMin
        function XLimMinValueChanged(app, event)
            %value = app.XLimMin.Value;
            app.okButton.UserData.xlimmin = app.XLimMin.Value;
        end

        % Value changed function: XLimMax
        function XLimMaxValueChanged(app, event)
            %value = app.XLimMax.Value;
            app.okButton.UserData.xlimmax = app.XLimMax.Value;
        end

        % Value changed function: YLimMin
        function YLimMinValueChanged(app, event)
            %value = app.YLimMin.Value;
            app.okButton.UserData.ylimmin = app.YLimMin.Value;
        end

        % Value changed function: YLimMax
        function YLimMaxValueChanged(app, event)
            %value = app.YLimMax.Value;
            app.okButton.UserData.ylimmax = app.YLimMax.Value;
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            xlimvals = [app.okButton.UserData.xlimmin, app.okButton.UserData.xlimmax];
            ylimvals = [app.okButton.UserData.ylimmin, app.okButton.UserData.ylimmax];
            % updateMainAppAxis(app, app.okButton.UserData.ax , xlimvals, ylimvals)
            for ii = 1:numel(app.okButton.UserData.ax)
                app.okButton.UserData.ax(ii).XLim = xlimvals;
                app.okButton.UserData.ax(ii).YLim = ylimvals;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.controlaxesUIFigure = uifigure('Visible', 'off');
            app.controlaxesUIFigure.Name = 'controlaxes';
            app.controlaxesUIFigure.CloseRequestFcn = createCallbackFcn(app, @controlAppAxesCloseRequest, true);
            app.SelectAxesLabel = uilabel(app.controlaxesUIFigure);
            app.SelectAxesLabel.Text = 'Select Axes';
            app.AxisList = uidropdown(app.controlaxesUIFigure);
            app.AxisList.Items = {'Auto correlogram', 'Temporal component', 'All receptive fields', 'Spatial component', 'PCA 1', 'PCA 2', 'PCA 3', 'All PCAs', 'Population Auto correlograms', 'Off parasol auto correlograms', 'On parasol auto correlograms', 'Off midget auto correlograms', 'On midget auto correlograms', 'Population temporal components', 'Off parasol temporal components', 'On parasol temporal components', 'Off midget temporal components', 'On midget temporal components', 'Population receptive fields', 'Off parasol receptive fields', 'On parasol receptive fields', 'Off midget receptive fields', 'On midget receptive fields', 'Population nonlinearities', 'Off parasol nonlinearities', 'On parasol nonlinearities', 'Off midget nonlinearities', 'On midget nonlinearities'};
            app.AxisList.ValueChangedFcn = createCallbackFcn(app, @AxisListValueChanged, true);
            app.AxisList.Tooltip = {''};
            app.AxisList.Value = 'Population receptive fields';
            app.XLimLabel = uilabel(app.controlaxesUIFigure);
            app.XLimLabel.Text = 'XLim';
            app.XLimMin = uieditfield(app.controlaxesUIFigure, 'numeric');
            app.XLimMin.ValueChangedFcn = createCallbackFcn(app, @XLimMinValueChanged, true);
            app.XLimMax = uieditfield(app.controlaxesUIFigure, 'numeric');
            app.XLimMax.ValueChangedFcn = createCallbackFcn(app, @XLimMaxValueChanged, true);
            app.XLimMax.Value = 800;
            app.YLimLabel = uilabel(app.controlaxesUIFigure);
            app.YLimLabel.Text = 'YLim';
            app.YLimMin = uieditfield(app.controlaxesUIFigure, 'numeric');
            app.YLimMin.ValueChangedFcn = createCallbackFcn(app, @YLimMinValueChanged, true);
            app.YLimMax = uieditfield(app.controlaxesUIFigure, 'numeric');
            app.YLimMax.ValueChangedFcn = createCallbackFcn(app, @YLimMaxValueChanged, true);
            app.YLimMax.Value = 600;
            app.okButton = uibutton(app.controlaxesUIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.cancelButton = uibutton(app.controlaxesUIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            app.applyButton = uibutton(app.controlaxesUIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            % Show the figure after all components are created
            app.controlaxesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlAppAxes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.controlaxesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.controlaxesUIFigure)
        end
    end
end